classdef Epilepsy_Detection_old_FD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Step1Menu                       matlab.ui.container.Menu
        FileUploadandPreProcessingMenu  matlab.ui.container.Menu
        OutliersMenu                    matlab.ui.container.Menu
        ComputingMenu                   matlab.ui.container.Menu
        Step2SeizuresMenu               matlab.ui.container.Menu
        GraphicsdisplayMenu             matlab.ui.container.Menu
        ResultsMenu                     matlab.ui.container.Menu
        Step3InterictalspikesMenu       matlab.ui.container.Menu
        GraphicsDisplayMenu             matlab.ui.container.Menu
        ResultsMenu_2                   matlab.ui.container.Menu
        Energytreshold1                 matlab.ui.control.NumericEditField
        EnergytresholdLabel_2           matlab.ui.control.Label
        EnergytresholdLabel             matlab.ui.control.Label
        seuil_pic_2                     matlab.ui.control.NumericEditField
        ThresholdlowfrequencyLabel      matlab.ui.control.Label
        seuil_pic                       matlab.ui.control.NumericEditField
        ThresholdhighfrequencyLabel     matlab.ui.control.Label
        Tmax_                           matlab.ui.control.NumericEditField
        MaximumdurationbetweentwospikesLabel  matlab.ui.control.Label
        Tmin_                           matlab.ui.control.NumericEditField
        MinimumdurationbetweentwospikesLabel  matlab.ui.control.Label
        nb_min_pics_                    matlab.ui.control.NumericEditField
        MinimumnumberofsuccesivespikesLabel  matlab.ui.control.Label
        dureeminpic_                    matlab.ui.control.NumericEditField
        MinimuninterrictalspikedurationLabel  matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        seuil                           matlab.ui.control.NumericEditField
        ThresholdLabel                  matlab.ui.control.Label
        dureemin                        matlab.ui.control.NumericEditField
        MinimunseizuredurationLabel     matlab.ui.control.Label
        KeyfactorsLabel                 matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        ThemetricLabel                  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        WhatcanthisalgorithmdoLabel     matlab.ui.control.Label
        EpilepsyDetectionAlgorithmLabel  matlab.ui.control.Label
    end


    properties (Access = public)
        yini % Description
        timeini % Description
        Compteurs % Description
        y_post_abe % Description
        file % Description
        path % Description
        fs % Description
        channel % Description
        date % Description
        time_format % Description
    end
    
    properties (Access = public)
    end
    
    methods (Access = public)
        
 
    end
    
    methods (Access = public)
        
        function sommeW1 = Walsh(app,x)
        % Hadamard matrices
        H2 = hadamard(2);
        H4 = hadamard(4);
        H8 = hadamard(8);
        H16 = hadamard(16);
        
        % Operators
        omega_Operator_2_1 = H2(2,:);
        omega_Operator_4_1 = H4(3,:);
        omega_Operator_8_1 = H8(5,:);
        omega_Operator_16_1 = H16(9,:);
        omega_Operator_4_2 = H4(4,:);
        omega_Operator_8_2 = H8(7,:);
        omega_Operator_16_2 = H16(13,:);
        
        % Tranform
        W_4_1 = conv( omega_Operator_4_1 , x );
        W_4_1 = W_4_1( 1 : length(W_4_1) - 3 );
        W_8_1 = conv( omega_Operator_8_1 , x );
        W_8_1 = W_8_1( 1 : length(W_8_1) - 7 );
        W_16_1 = conv( omega_Operator_16_1 , x );
        W_16_1 = W_16_1( 1 : length(W_16_1) - 15 );
        W_4_2 = conv( omega_Operator_4_2 , x );
        W_4_2 = W_4_2( 1 : length(W_4_2) - 3 );
        W_8_2 = conv( omega_Operator_8_2 , x );
        W_8_2 = W_8_2( 1 : length(W_8_2) - 7 );
        W_16_2 = conv( omega_Operator_16_2 , x );
        W_16_2 = W_16_2( 1 : length(W_16_2) - 15 );
        
        % Addition 
        sommeW1 = W_4_1 + W_8_1 + W_16_1 ;
        sommeW1 = abs( sommeW1 );
        sommeW2 = W_4_2 + W_8_2 + W_16_2 ;
        sommeW2 = abs( sommeW2 );         
            
        end
       end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: FileUploadandPreProcessingMenu
        function FileUploadandPreProcessingMenuSelected(app, event)
            % Creation of the window on which graphics will be shown
            fig=uifigure('Name','File Upload and Pre-Processing ');
            longueur=fig.Position(3);
            hauteur=fig.Position(4);
            fig.AutoResizeChildren = 'on';
            datacursormode(fig)
                                         
            % Creation of the button and its callback function
            btnfichier=uibutton(fig,'push','Text', 'Browse File',...
               'Position',[400, 10, 100, 22],...
               'ButtonPushedFcn', @(btn,event) btnfichierPushed());
           
            function btnfichierPushed()   
                
            % Pre-processing (take informations from the first 5 lines and save the next lines as the file) 
            % THE FILE HAS TO BE LIKE THE TEMPLATE FILE 
            [app.file,app.path] = uigetfile('*.txt');
            tic
            
            fid = fopen(strcat(app.path,app.file),'r');
            numLines = 5;
            your_text = cell(numLines,1);
            for ii = 1:numLines
                your_text(ii) = {fgetl(fid)}; 
            end
            fclose(fid);
            
            myInfo = regexp(your_text(1),'\d+\.?\d*','match');
            app.fs=1/(str2double(myInfo{1}));
            myInfo = regexp(your_text(4),'\d*','match');
            app.channel = str2double(myInfo{1});
            myInfo = your_text{2};
            myInfo=myInfo((11:length(myInfo)-2));    
            app.date=myInfo;
            
            fid = fopen(strcat(app.path,app.file),'rt');
            C = textscan(fid, '%f%f', 'MultipleDelimsAsOne',true, 'Delimiter','[;', 'HeaderLines',5);
            fclose(fid);

            % On stocke les infos dans matlab     
            app.yini=C{1,2};
            app.timeini=C{1,1};
            app.time_format=seconds(app.timeini);
            app.time_format.Format='hh:mm:ss.SSS';
            
            toc
            disp('1/2')
            disp('Conversion into Matlab data ')


            % Creation of the graphic of the signal 
            tic
            p=uipanel(fig,'Position',[0.1*longueur 0.1*hauteur 0.8*longueur 0.8*hauteur]);
            ax=uiaxes(p,'Position',[0.05*longueur 0.05*hauteur 0.7*longueur 0.7*hauteur]);
            plot(ax,app.time_format,app.yini)  
            text(ax,0.01,0.01, app.date, ...
                'Units', 'normalized', ...   
                'HorizontalAlignment', 'left', ...
                'VerticalAlignment', 'bottom');
            title(ax , 'Original signal with outliers' )
            xlabel(ax,'Time (in hours:minutes:seconds)')
            ylabel(ax,'Voltage (in millivolts)')
            grid(ax,'minor')
            toc
            disp('2/2') 
            disp('Display of the signal')
            disp('-----------------------------------------------')
            
            end
        end

        % Menu selected function: OutliersMenu
        function OutliersMenuSelected(app, event)
            
            % Creation of histograms to see the distribution 
            tic
            HBOS=[];
            [his,edges]=histcounts(log10(app.yini));
            %[his,edges]=histcounts((app.yini));
            x= 10.^edges( 1 : length(edges)-1 ) + diff( 10.^edges )/2;
            for i=1:length(his)
            HBOS=[HBOS ; log(1 + his(i) ) ];
            end
            HBOS=HBOS/max(HBOS);

            fig = uifigure('Name','Outliers');
            longueur=fig.Position(3);
            hauteur=fig.Position(4);
            fig.AutoResizeChildren = 'on';
            datacursormode(fig)
            p=uipanel(fig,'Position',[0.05*longueur 0.15*hauteur 0.8*longueur 0.8*hauteur]);
            p.AutoResizeChildren = 'off';
            ax1 = subplot(3,1,1,'Parent',p);
            plot(ax1,app.time_format,app.yini)
            title(ax1 , 'Original signal with outliers' )
            xlabel(ax1,'Time (in hours:minutes:seconds)')
            ylabel(ax1,'Voltage (in millivolts)')
            ax2 = subplot(3,1,2,'Parent',p);
            plot(ax2,x,HBOS)
            title(ax2 , 'ln(1 + Histogram)' )
            xlabel(ax2,'Values')
            ylabel(ax2,'Occurence')
            ax3 = subplot(3,1,3,'Parent',p);
            histogram(ax3,app.yini,10.^edges)
            text(ax3,0.01,0.01, app.date, ...
                'Units', 'normalized', ...   
                'HorizontalAlignment', 'left', ...
                'VerticalAlignment', 'bottom');            
            title(ax3 , 'Histogram' )
            xlabel(ax3,'Values')
            ylabel(ax3,'Occurence')
            linkaxes([ax2 ax3],'x')
            grid([ax1 ax2 ax3],'minor')
            
            % Creation of fill boxes to put the wanted min and max values
            lab=uilabel(fig,'Text',"Fill in the two boxes with the desired min and max values in order", 'Position',[0.05*longueur 0.10*hauteur 500 15]);
            casemin=uieditfield(fig, 'numeric', ...
                                  'Position',[0.05*longueur 0.05*hauteur 102 15]); %left bottom width heigth en pixel --> left et bottom c'est le nombre de pixels de distance entre 
                                                             % la checkbox et la figure et le reste c'est la taille de la checkbox
           
                                                             
            casemax=uieditfield(fig, 'numeric', ...
                                  'Position',[0.35*longueur 0.05*hauteur 102 15]);
            
                             
            % Creation of the button and its callback function 
            btnabe=uibutton(fig,'push','Text', 'Remove outliers',...
               'Position',[0.70*longueur 0.05*hauteur, 200, 22],...
               'ButtonPushedFcn', @(btn,event) btnabePushed());
            toc
            disp('1/2')
            disp('Initialization and first graphic')
           
            function btnabePushed()  %callback function
           tic
           delete([ax1 ax2 ax3])    
               
           % Replacing outliers
           app.Compteurs=[];
           reponse_min=casemin.Value;
           reponse_max=casemax.Value;
           yabe=app.yini;
           
           for elem=1:length(yabe)
    
           if yabe(elem)<=reponse_min || yabe(elem)>=reponse_max
               
           if yabe(elem)~=0
           
    
           if yabe(elem)<=reponse_min
           compteur_avant=1;
           compteur_apres=1;
           while yabe(elem-compteur_avant)<=reponse_min || elem-compteur_avant == 1
           compteur_avant=compteur_avant+1;
           end
           while yabe(elem+compteur_apres)<=reponse_min || elem+compteur_apres == length(yabe)
           compteur_apres=compteur_apres+1;    
           end


           elseif yabe(elem)>=reponse_max
           compteur_avant=1;
           compteur_apres=1;
           while yabe(elem-compteur_avant)>=reponse_max
           compteur_avant=compteur_avant+1;
           end
           while yabe(elem+compteur_apres)>=reponse_max
           compteur_apres=compteur_apres+1;        
           end  

           end
           app.Compteurs=[app.Compteurs ; [elem,compteur_avant,compteur_apres]];
           yabe(elem) = ( yabe(elem-compteur_avant) + yabe(elem+compteur_apres) )/2;
           end
           end
           end
           
           app.y_post_abe=yabe;
           
           % Plot    
           axy1=subplot(3,1,1,'Parent',p);
           plot(axy1,app.time_format,app.yini)
           title(axy1 , 'Original signal with outliers' )
           xlabel(axy1,'Time (in hours:minutes:seconds)')
           ylabel(axy1,'Voltage (in millivolts)')           
           axy2=subplot(3,1,2,'Parent',p);
           plot(axy2,app.time_format,yabe)
           title(axy2 , 'Signal without outliers' )
           xlabel(axy2,'Time (in hours:minutes:seconds)')
           ylabel(axy2,'Voltage (in millivolts)')               
           axy3=subplot(3,1,3,'Parent',p);
           plot(axy3,x,HBOS)
           text(axy3,0.01,0.01, app.date, ...
               'Units', 'normalized', ...   
               'HorizontalAlignment', 'left', ...
               'VerticalAlignment', 'bottom');           
           title(axy3 , 'ln(1 + Histogram)' )
           xlabel(axy3,'Values')
           ylabel(axy3,'Occurence')           
           linkaxes([axy1 axy2],'x') 
           grid([axy1 axy2 axy3],'minor')
           
           toc
           disp('2/2')
           disp('Second graphic')
           disp('-----------------------------------------------')
               
          
           end


        end

        % Menu selected function: GraphicsdisplayMenu
        function GraphicsdisplayMenuSelected2(app, event)
%------------------------ Load data  -------------------------------%
[file_affichage,path_affichage] = uigetfile('*.mat');
cd(path_affichage)
tic
load( sprintf( '%s.mat', file_affichage(1:length(file_affichage)-4)) )
toc
disp('1/2')
disp('Load data')


%--------------------- Graphics  ----------------------------%

tic
max_y_pb = max(abs(y_pb)); 
max_ymed = max(abs(ymed)); 
seuilenergie=app.Energytreshold1;

fig=uifigure('Name','Graphics Display');
fig.AutoResizeChildren = 'off';
datacursormode(fig)
ax1 = subplot(3,1,1,'Parent',fig);
plot(ax1,time_format_debut,y_apres_abe)
text(ax1,0.01,0.01, date_, ...
     'Units', 'normalized', ...   
     'HorizontalAlignment', 'left', ...
     'VerticalAlignment', 'bottom');
title(ax1 , 'Signal without outliers' )
xlabel(ax1,'Time (in hours:minutes:seconds)')
ylabel(ax1,'Voltage (in millivolts)')
ax2 = subplot(3,1,2,'Parent',fig);
plot(ax2,time_format_,ymed,time_format_,(seuilenergie.Value*ones(1,length(ymed)))) %change here the treshold on the plot
set(ax2,'YScale','log')
title(ax2 , 'Energy metric' )
xlabel(ax2,'Time (in hours:minutes:seconds)')
ylabel(ax2,'Energy metric')
ax3 = subplot(3,1,3,'Parent',fig);
plot(ax3,time_CF, CF );
title(ax3 , 'Combined Feature' )
xlabel(ax3,'Time (in hours:minutes:seconds)')
ylabel(ax3,'CF')
linkaxes( [ax1 ax2 ax3], 'x' );
grid([ax1 ax2 ax3],'minor')

dossier=path_affichage(1:length(path_affichage)-8);
cd(dossier)

toc
disp('2/2')
disp('Display graphics')
disp('-----------------------------------------------')
        end

        % Menu selected function: ComputingMenu
        function ComputingMenuSelected(app, event)
        % Initialization 
        ydebut= app.y_post_abe;
        time= app.timeini;
        timedebut= app.timeini;
        len=length(time);
        
        
        %-------------------------- Disconnections ---------------------------------%
        tic
        % Find indexes of the disconnections and add values to fill the void
        indices_deco=find( (diff(time) -0.001953) >= 10^(-3) ); %change here if the sampling time is different from mine
        
        if length(indices_deco)>=1   %if we have disconnections in this file
        
        differences_deco=[];
        distance_deco=diff(indices_deco)*0.001953;
        for ind=1:length(indices_deco)
        difference=time(indices_deco(ind)+1)-time(indices_deco(ind));
        differences_deco=[differences_deco difference];
        end
        
        
        nb_pts_a_rajouter_=differences_deco*app.fs; 
        nb_pts_a_rajouter=fix(nb_pts_a_rajouter_);
        nb_pts_en_plus=sum(nb_pts_a_rajouter);
        
        newy=ydebut;
        newtime=time;
        enplus=0;
        for ind2=1:length(indices_deco)
        ajout=zeros(nb_pts_a_rajouter(ind2),1);
        ajoutini=time(indices_deco(ind2));
        for ind3=1:length(ajout)
            ajout(ind3)=ajoutini+ind3*0.001953;
        end    
        newtime=[newtime(1:indices_deco(ind2)+enplus);ajout;newtime(indices_deco(ind2)+enplus+1:len+enplus)];
        if indices_deco(ind2)<app.fs+1
        ajout2_liste=ydebut(indices_deco(ind2):indices_deco(ind2)+app.fs);
        elseif indices_deco(ind2)>(length(newy)-app.fs)
        ajout2_liste=ydebut(indices_deco(ind2)-app.fs:indices_deco(ind2));
        else        
        ajout2_liste=ydebut(indices_deco(ind2)-app.fs:indices_deco(ind2)+app.fs);
        end 
        ajout2=mean(ajout2_liste)*ones(nb_pts_a_rajouter(ind2),1);
        newy=[newy(1:indices_deco(ind2)+enplus);ajout2;newy(indices_deco(ind2)+enplus+1:len+enplus)];
        enplus=enplus+nb_pts_a_rajouter(ind2);
        end
        
        y=newy;
        time=newtime;  
        time_format_=seconds(time);
        time_format_.Format='hh:mm:ss.SSS';
        
        
        else   %if there are no disconnections  
        y=ydebut;     
        nb_pts_en_plus=0;
        time_format_=app.time_format;
        
        end
        
        toc
        disp('1/5')
        disp('Disconnections')
        
        %---------------------- Signal Processing -----------------------------%
        tic
        %Normalization between 0 and 1 
        minsignal=min(y);
        maxsignal=max(y);
        ynormalise=(y-minsignal)/(maxsignal-minsignal);
        
        % Band pass filter
        y_pb = bandpass(ynormalise,[5 75],app.fs,'Steepness',0.5);
        %y_pb=ynormalise;
        % I took off the first second (512 pts) and the last to not see the windowing effect (i did not succeed to take it off even with a Hamming window)
        y_pb=y_pb(512:length(y_pb)-512);
        time=time(512:length(time)-512);
        time_format_=time_format_(512:length(time_format_)-512);
        
        
        % We set the negative points to 0 and we square
        ypuissance=y_pb;
        for i=1:length(y_pb)
            if y_pb(i)<0
                ypuissance(i)=0;
            end
        end
        
        ypuissance=ypuissance.^4;
        
        % Moving mean over 2 seconds 
        ymed=movmean(ypuissance,app.fs*2); %change here if you want to change the duration of the sliding window. 2s work out fine for me
        
        medymed=median(ymed)
        
        
        %We replace aberrant values inside the metric by 0 
        for i=1:length(ymed)
            if  ymed(i)<10^(-10)
                ymed(i)=0;
            end
        end
        
        toc
        disp('2/5')
        disp('Signal processing')


        %% Combined Feature
        tic
        energie=(log10( ymed(1:512:length(ymed)) )+15);
        seuilenergie=app.Energytreshold1; % change here to change the threshold of the minimal energy
        %seuilenergie=medymed;
        
        if length(find(energie>=8))==0
        CF=energie;
        else        
        temps_delta=[];
        compteur_delta=0;
        compteur_vrai=0 ;
        for j=1:((10*length(time))/app.fs)   
            compteur_vrai=compteur_vrai+1;
            if ymed(floor(j/10)*512+mod(j,10)*51)>=seuilenergie.Value  
               compteur_delta=compteur_delta+1;
            else
               if compteur_delta >=1 
               temps_delta=[temps_delta;compteur_vrai/10-compteur_delta/10 compteur_vrai/10];
               end
               compteur_delta=0;
            end        
        end
        
        if length(temps_delta)==0
        CF=energie;    
        else
        delta=zeros(length(energie),1);     
        stockage=1;
        for elem=1:length(energie)
            if elem>=temps_delta(stockage,1) && elem<=temps_delta(stockage,2)         
                delta(elem)=temps_delta(stockage,2)-temps_delta(stockage,1);
            elseif elem>temps_delta(stockage,2)
                stockage=stockage+1;
                if stockage>length(temps_delta(:,1))
                   stockage=stockage-1;
                end
            end
        end
        
        CF=[];
        for compt=1:length(energie)
        CF=[CF energie(compt)*delta(compt)];
        end
        
        end
        
        end 
         
        for elem=1:length(CF)
        if energie(elem)<=0
            CF(elem)=0;
        end
        end


        time_CF=1:length(CF);
        time_CF=seconds(time_CF);
        time_CF.Format='hh:mm:ss';
        
        toc
        disp('3/5')
        disp('Combined Feature')
                
        
        %------------------------------ Number of possible seizures  -------------------------------------% 
        tic

        % Number, position in time and duration of possible seizures (time values are calculated to 0.1s accuracy)
        seuilenergie=app.Energytreshold1;
        %seuilenergie=medymed;
        nombre_crise=0;
        compteur=-1;
        temps_crises=[];
        duree_crises=[];
        ind_crises = [];
        for j=1:((10*length(time))/app.fs)   
            if ymed( floor(j/10)*512+mod(j,10)*51 )>=seuilenergie.Value 
               compteur=compteur+1;
               if compteur==0
                   start = floor(j/10)*512+mod(j,10)*51;
               end
            else
               if compteur>=20
                   nombre_crise=nombre_crise+1;
                   temps_crises=[temps_crises time( floor(j/10)*512+mod(j,10)*51 )-compteur/10 time( floor(j/10)*512+mod(j,10)*51 )];  
                   ind_crises = [ind_crises; start floor(j/10)*512+mod(j,10)*51];
                   duree_crises=[duree_crises compteur/10];
                    
               end
               compteur=-1;
            end 
        end     
        
        % Data storage in infos_crises
        N=length(duree_crises);
        infos_crises = [];
        for k=1:N
            infos_crises=[infos_crises;[k temps_crises(2*k-1) temps_crises(2*k) duree_crises(k)]];
        end    
        
        
        toc
        disp('4/5')
        disp('Informations')


        %%%%%%%%%%%%%%%%%%%%%%%%%%%%% Save data in a .mat file %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        tic
        cd(app.path)
        if ~exist('Results' , 'dir')
        mkdir Results
        end 
        
        dossier=strcat(app.path,'\Results');
        cd(dossier)
        
        y_apres_abe=app.y_post_abe;
        date_ = app.date;
        fs_ = app.fs;
        channel_ = app.channel;
        nb_abe=length(app.Compteurs);
        time_format_debut=app.time_format;
        
        save( sprintf( 'Results_%s', app.file(1:length(app.file)-4)), 'ind_crises' ,'timedebut','time_format_','time_format_debut','time_CF','time','y','y_pb','ymed','y_apres_abe','infos_crises','indices_deco','nombre_crise','N', 'energie', 'CF','date_','channel_','fs_','nb_pts_en_plus','nb_abe')
        variableList = {'temps_delta', 'delta'};
        for variableIndex = 1:length(variableList)
            if exist(variableList{variableIndex})
                save( sprintf( 'Results_%s', app.file(1:length(app.file)-4)), variableList{variableIndex}, '-append')
            end
        end    
        
        dossier=app.path;
        cd(dossier)
        
        toc  %fin du timer
        disp('5/5')
        disp('Saving of the .mat file')
        disp('-----------------------------------------------')
                    
                            
        end

        % Menu selected function: ResultsMenu
        function ResultsMenuSelected(app, event)
        % Load data
        [file_affichage,path_affichage] = uigetfile('*.mat');
        cd(path_affichage)
        tic
        load( sprintf( '%s.mat', file_affichage(1:length(file_affichage)-4)) , 'ind_crises' , 'infos_crises','nombre_crise','time_format_debut','time_format_','time_CF','time', 'timedebut','y_apres_abe','CF','date_','nb_pts_en_plus','nb_abe' )
        toc
        disp('Load data')
        disp('-----------------------------------------------')
            
        % We check if the possible seizures verify the user's preferences
        seuil_final = app.seuil.Value;   % Threshold
        duree_min = app.dureemin.Value;  % min seizure duration      
        if nombre_crise > 0
        vrai_infos_crises=infos_crises;
        vrai_ind_crises=ind_crises;
        
        vrai_infos_crises( (infos_crises(:,4) < app.dureemin.Value) , : ) = []; % user preference on min duration  
        vrai_ind_crises( (infos_crises(:,4) < app.dureemin.Value) , : ) = [];
        
        indices_lignes_non = []; % user preference on threshold
        for i=1:length(vrai_infos_crises(:,1))
        tdebut=ceil( vrai_infos_crises(i,2) );
        len_old = length(indices_lignes_non);
        while tdebut <= floor(vrai_infos_crises(i,3)) && length(indices_lignes_non) == len_old
            if CF(tdebut) < app.seuil.Value
               indices_lignes_non = [indices_lignes_non ; i]; 
            end
        tdebut=tdebut+1;
        if tdebut == floor(vrai_infos_crises(i,3))
            break
        end
        end
        end
        vrai_infos_crises( indices_lignes_non , : ) = [];
        vrai_ind_crises( indices_lignes_non , : ) = [];
        vrai_nombre_crises = length( vrai_infos_crises(:,1) ) ;
        else
        vrai_nombre_crises=0;
        vrai_infos_crises=['No seizure'];
        end
        
        %% Results
        fprintf('According to you, in this recording, there are : \n - %d outliers \n ', nb_abe );      
        fprintf('- %d disconnections \n ', nb_pts_en_plus );
        fprintf('- %d seizure(s) \n ', vrai_nombre_crises);
        if vrai_nombre_crises > 0
        Temps1=[];
        Temps2=[];
        for l=1:vrai_nombre_crises
        temps1=seconds( vrai_infos_crises(l,2) );
        temps1.Format='hh:mm:ss.SSS';
        temps1=datestr(temps1, 13);
        Temps1=[Temps1;temps1];
        temps2=seconds( vrai_infos_crises(l,3) );
        temps2.Format='hh:mm:ss.SSS';   
        temps2=datestr(temps2,13);
        Temps2=[Temps2 temps2];
        formatSpec = 'The seizure number %d begins at %s seconds and finishes at %s seconds which corresponds to a duration of %.1f seconds.\n';
        fprintf(formatSpec, l,temps1,temps2,vrai_infos_crises(l,4) );
        end
        else
        fprintf('There are no seizure in this recording \n');    
        end
        fprintf('We specify that all positions in time are calculated to the nearest 0.1s, which can lead to errors.\n');
        
        % Save new data
        if vrai_nombre_crises >= 1
        save( sprintf( '%s.mat', file_affichage(1:length(file_affichage)-4)) , 'vrai_infos_crises' , 'vrai_nombre_crises' , 'vrai_ind_crises', '-append' )
        else 
        save( sprintf( '%s.mat', file_affichage(1:length(file_affichage)-4)) , 'vrai_nombre_crises' , '-append' )    
        end
        
        % Graphics
        fig=uifigure('Name','Signal without outliers');
        fig.AutoResizeChildren = 'on';
        datacursormode(fig)
        p=uipanel(fig,'Position',[40 40 400 400]);
        ax=uiaxes(p,'Position',[45 45 350 350]);
        plot(ax, time_format_debut , y_apres_abe ) 
        text(ax,0.01,0.01, date_, ...
             'Units', 'normalized', ...   
             'HorizontalAlignment', 'left', ...
             'VerticalAlignment', 'bottom');
        title(ax , 'Signal avec valeurs aberrantes supprimées' )
        xlabel(ax,'Time (in hours:minutes:seconds)')
        ylabel(ax,'Voltage (in millivolts)')      
        grid(ax,'minor')
                
        if vrai_nombre_crises >= 1
        Power=[];   
        for elem=1:vrai_nombre_crises
        ind_crise=abs(timedebut-vrai_infos_crises(elem,2))<(1/512);    
        ind_crise=find(ind_crise);
            if isempty(ind_crise)==1;
               ind_crise=vrai_ind_crises(elem,:);
            else
               ind_crise=ind_crise(1);
            end


        % Power of each seizure
        duree_crise=vrai_infos_crises(elem,4);
        puissance=y_apres_abe( ind_crise : floor( ind_crise + 512*floor(duree_crise)+mod(duree_crise,10)*51 ) );
        puissance = puissance - mean(puissance);
        puissance = sum(puissance.^2) / (duree_crise*(1/512));
        Power=[Power puissance];
        
        fig=uifigure('Name',sprintf('Seizure number %d', elem));
        fig.AutoResizeChildren = 'on';
        datacursormode(fig)
        p=uipanel(fig,'Position',[40 40 400 400]);
        ax=uiaxes(p,'Position',[45 45 350 350]);
        plot(ax, time_format_debut( max( 1 , ind_crise - 2*5120 )   : min( length(time_format_debut) , 2*5120 + ind_crise + fix(vrai_infos_crises(elem,4))*512 ) ) , y_apres_abe( max( 1 , ind_crise - 2*5120 )   : min( length(time_format_debut) , 2*5120 + ind_crise + fix(vrai_infos_crises(elem,4))*512 ) ) )        
        text(ax,0.01,0.01, date_, ...
             'Units', 'normalized', ...   
             'HorizontalAlignment', 'left', ...
             'VerticalAlignment', 'bottom');        
        title(ax , sprintf('Seizure number %d', elem) )
        xlabel(ax,'Time (in hours:minutes:seconds)')
        ylabel(ax,'Voltage (in millivolts)') 
        grid(ax,'minor')
        end
        end
        
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%% Save in an Excel file %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        if vrai_nombre_crises >= 1
        Id=( 1:length( vrai_infos_crises(:,1) ) )';
        Start = Temps1;
        Duration = vrai_infos_crises(:,4);
        Power=Power';
        nb_heure=datestr( time_format_( length(time_format_) ), 13 );
        threshold=app.seuil.Value;
        Min_Seizure_Duration=app.dureemin.Value;
        
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{'SEIZURES'},'Feuil1','A1');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{'hours:minutes:seconds'},'Feuil1','B2');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{'seconds'},'Feuil1','C2');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{'mV^2/s'},'Feuil1','D2');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('%d Outliers', nb_abe ) },'Feuil1','K3');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('%d Disconnections', nb_pts_en_plus ) },'Feuil1','K4');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('%d Seizures', vrai_nombre_crises ) },'Feuil1','K5');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('%s', date_ ) },'Feuil1','K6');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('%s hours of recording', nb_heure ) },'Feuil1','K7');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('Power : %f (mV^2/s) ', puissance ) },'Feuil1','K8');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ 'User key factors' },'Feuil1','M3');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('Threshold seizure : %f', threshold ) },'Feuil1','M4');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('Min seizure duration : %f', Min_Seizure_Duration ) },'Feuil1','M5');
        
        if vrai_nombre_crises == 1      
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{'Id'},'Feuil1','A3');  
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{'Start'},'Feuil1','B3'); 
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{'Duration'},'Feuil1','C3'); 
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{'Power'},'Feuil1','D3'); 
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{'1'},'Feuil1','A4');  
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('%s', temps1 )},'Feuil1','B4'); 
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('%f', Duration(1) )},'Feuil1','C4'); 
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('%f', Power(1) )},'Feuil1','D4');         
        else    
        T=table(Id,Start,Duration,Power);
        writetable( T, sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)),'Sheet','Feuil1','Range','A3');
        end
        
        end
        dossier=path_affichage(1:length(path_affichage)-8);
        cd(dossier)
        end

        % Menu selected function: GraphicsDisplayMenu
        function GraphicsDisplayMenuSelected(app, event)
        % Load data
        [file_affichage,path_affichage] = uigetfile('*.mat');
        cd(path_affichage)
        tic
        load( sprintf( '%s.mat', file_affichage(1:length(file_affichage)-4)), 'time_format_','y','date_')
        toc
        disp('Load data')
        disp('-----------------------------------------------')            
        
        % Display of the Walsh metric to the user
        tic
        % Computing
        yhigh=highpass(y,80,512); %to get only HFOs
        ylow=lowpass(y,20,512); %to get IID + artifact
        sommeW1 = Walsh(app,yhigh); 
        sommeW2 = Walsh(app,ylow);
        
        % Plot
        y=y(512:length(y)-512);
        sommeW1=sommeW1(512:length(sommeW1)-512); %high frequency
        sommeW2=sommeW2(512:length(sommeW2)-512); %low frequency
        
        fig=uifigure('Name','Graphics Display');
        fig.AutoResizeChildren = 'off';
        datacursormode(fig)
        ax1 = subplot(3,1,1,'Parent',fig);
        plot(ax1, time_format_ , y )
        text(ax1,0.01,0.01, date_, ...
             'Units', 'normalized', ...   
             'HorizontalAlignment', 'left', ...
             'VerticalAlignment', 'bottom');
        title(ax1 , 'Signal without outliers' )
        xlabel(ax1,'Time (in hours:minutes:seconds)')
        ylabel(ax1,'Voltage (in millivolts)')        
        ax2 = subplot(3,1,2,'Parent',fig); %high frequency
        plot(ax2, time_format_ , sommeW1 )
        title(ax2 , 'Walsh metric high frequency' )
        xlabel(ax2,'Time (in hours:minutes:seconds)')
        ylabel(ax2,'Walsh metric')        
        ax3 = subplot(3,1,3,'Parent',fig); %low frequency
        plot(ax3, time_format_ , sommeW2 )
        title(ax3 , 'Walsh metric low frequency' )
        xlabel(ax3,'Time (in hours:minutes:seconds)')
        ylabel(ax3,'Walsh metric')        
        linkaxes([ax1 ax2 ax3],'x')
        
        
        toc
        disp('Computing, display graphic')
        disp('-----------------------------------------------') 
        
        
        dossier=path_affichage(1:length(path_affichage)-8);
        cd(dossier)
        
        end

        % Menu selected function: ResultsMenu_2
        function ResultsMenu_2Selected(app, event)
        % Load data
        [file_affichage,path_affichage] = uigetfile('*.mat');
        cd(path_affichage)
        tic
        load( sprintf( '%s.mat', file_affichage(1:length(file_affichage)-4)) , 'fs_', 'date_' , 'nb_pts_en_plus' , 'nb_abe'  , 'vrai_infos_crises' , 'vrai_nombre_crises', 'time_format_','y','date_' )
        toc
        disp('Load data')
        disp('-----------------------------------------------')                 
        
        
        % Computing
        % User choices
        nb_min_pics = app.nb_min_pics_.Value; 
        Tmax = app.Tmax_.Value; 
        Tmin = app.Tmin_.Value; 
        dureeminpic = app.dureeminpic_.Value; 
        seuil_pointes = app.seuil_pic.Value;
        seuil_pointes_2= app.seuil_pic_2.Value; 
        fs=fs_; 
        dureeminpic_nb = floor( dureeminpic * fs ); 
        
        % Walsh metric and we set to 0 values < threshold (user choice)
        yhigh=highpass(y,80,512); 
        ylow=lowpass(y,20,512); 
        sommeW1 = Walsh(app,yhigh); 
        sommeW2 = Walsh(app,ylow); 
        sommeW1=sommeW1(512:length(sommeW1)-512); 
        sommeW2=sommeW2(512:length(sommeW2)-512); 
        indices = find(sommeW1 > seuil_pointes); 
        indices_2 = find(sommeW2 > seuil_pointes_2); 
        liste2 = zeros( length(sommeW1) , 1 ); 
        liste6 = zeros( length(sommeW2) , 1 ); 
        valmax = 0; 
        nb_pts = 1; 
        for elem1=1:length(indices)-1
            if indices(elem1+1) == indices(elem1)+1
                valmax = max( valmax , sommeW1(indices(elem1)) );
                nb_pts = nb_pts + 1;
            else
                if nb_pts >= 2
                liste2( indices(elem1)-nb_pts+1 : indices(elem1) ) = valmax*ones(nb_pts,1);
                else     
                liste2(indices(elem1))=sommeW1(indices(elem1));
                end
            valmax = 0;
            nb_pts = 1;
            end    
        end
        
        %low frequency
        
        valmax = 0; 
        nb_pts = 1; 
        for elem5=1:length(indices_2)-1
            if indices_2(elem5+1) == indices_2(elem5)+1
                valmax = max( valmax , sommeW2(indices_2(elem5)) );
                nb_pts = nb_pts + 1;
            else
                if nb_pts >= 2
                liste6( indices_2(elem5)-nb_pts+1 : indices_2(elem5) ) = valmax*ones(nb_pts,1);
                else     
                liste6(indices_2(elem5))=sommeW2(indices_2(elem5));
                end
            valmax = 0;
            nb_pts = 1;
            end    
        end
        
        %high frequency
        
        if indices(elem1+1) == indices(elem1)+1
            liste2( indices(elem1+1)-nb_pts+1 : indices(elem1+1) ) = valmax*ones(nb_pts,1);
        else   
            liste2( indices(elem1+1) ) = sommeW1( indices(elem1+1) );
        end
        
        
        %low frequency
        
        if indices_2(elem5+1) == indices_2(elem5)+1
            liste6( indices_2(elem5)-nb_pts+1 : indices_2(elem5) ) = valmax*ones(nb_pts,1);
        else   
            liste6( indices_2(elem5+1) ) = sommeW2( indices_2(elem5+1) );
        end
        
        
        % Reunion of close enough events (user choice)
        indices_new = find(liste2 > 0);
        indices_new_2 = find(liste6 > 0);
        diff_indices_new = diff(indices_new);
        diff_indices_new_2 = diff(indices_new_2);
        nb_pts_reu = 8; % change here to change min distance between two events =8 is good!
        indices_reu = find( diff_indices_new > 1 & diff_indices_new < nb_pts_reu );
        indices_reu_2 = find( diff_indices_new_2 > 1 & diff_indices_new_2 < nb_pts_reu );
        liste3=liste2;
        liste7=liste6;
        
        %high frequency
        
        for elem2=1:length(indices_reu)  
            somme_up = 0;
            compteur_sup = 0;
            while diff_indices_new( indices_reu(elem2) + compteur_sup ) < nb_pts_reu %|| indices_reu(elem2) + compteur_sup == length(liste3)
                compteur_sup = compteur_sup +1;
                if (indices_reu(elem2)+compteur_sup)>length(diff_indices_new)
                    break
                end
            end 
            somme_down = 0;
            compteur_inf = 1;
        
            if indices_reu(elem2) - compteur_inf ==0
               elem2=elem2+1; 
            end
            
            while diff_indices_new( indices_reu(elem2) - compteur_inf ) < nb_pts_reu   %|| indices_reu(elem2) - compteur_inf == 1
                compteur_inf = compteur_inf +1;
                if indices_reu(elem2)-compteur_inf ==0
                    elem2=elem2+1;
                end
            end
            
            valeur = max( liste2( indices_new( ( indices_reu(elem2) ) ) ) , liste2( indices_new( ( indices_reu(elem2) ) ) + diff_indices_new( indices_reu(elem2) ) ) );
            somme = diff_indices_new( indices_reu(elem2) ) + compteur_inf + compteur_sup;
            
            liste3( indices_new( ( indices_reu(elem2) ) ) - compteur_inf : indices_new( ( indices_reu(elem2) ) ) + diff_indices_new( indices_reu(elem2) ) + compteur_sup ) = valeur*ones(somme+1,1);
        end        
        
        %low frequency
        
        %nb_pts_reu =200; %test avec 50
        
        for elem6=1:length(indices_reu_2)  
            somme_up = 0;
            compteur_sup = 0;
            while diff_indices_new_2( indices_reu_2(elem6) + compteur_sup ) < nb_pts_reu %|| indices_reu(elem2) + compteur_sup == length(liste3)
                compteur_sup = compteur_sup +1;
                if (indices_reu_2(elem6)+compteur_sup)>length(diff_indices_new_2)
                    break
                end
            end 
            somme_down = 0;
            compteur_inf = 1;
        
            if indices_reu_2(elem6) - compteur_inf ==0
               elem6=elem6+1; 
            end
            
            while diff_indices_new_2( indices_reu_2(elem6) - compteur_inf ) < nb_pts_reu   %|| indices_reu(elem2) - compteur_inf == 1
                compteur_inf = compteur_inf +1;
                if indices_reu_2(elem6)-compteur_inf ==0
                    elem6=elem6+1;
                end
            end
            
            valeur_2 = max( liste6( indices_new_2( ( indices_reu_2(elem6) ) ) ) , liste6( indices_new_2( ( indices_reu_2(elem6) ) ) + diff_indices_new_2( indices_reu_2(elem6) ) ) );
            somme_2 = diff_indices_new_2( indices_reu_2(elem6) ) + compteur_inf + compteur_sup;
            
            liste7( indices_new_2( ( indices_reu_2(elem6) ) ) - compteur_inf : indices_new_2( ( indices_reu_2(elem6) ) ) + diff_indices_new_2( indices_reu_2(elem6) ) + compteur_sup ) = valeur_2*ones(somme_2+1,1);
         end 
        
        
        % We set to 0 the metric inside of seizures
        tic
        if vrai_nombre_crises >= 1
        liste4=liste3;
        liste8=liste7;
        load( sprintf( '%s.mat', file_affichage(1:length(file_affichage)-4)) , 'vrai_ind_crises')
        for i=1:vrai_nombre_crises
            nombre=vrai_ind_crises(i,2)-vrai_ind_crises(i,1)+1;
            liste4( vrai_ind_crises(i,1) : vrai_ind_crises(i,2) ) = zeros(nombre,1);
            liste8( vrai_ind_crises(i,1) : vrai_ind_crises(i,2) ) = zeros(nombre,1);
        end
        
        else
        liste4=liste3;
        liste8=liste7;
        end


        % Suppression of not long enough events (user choice)
        [pics,ind_pics]=findpeaks(liste4);
        [pics_4,ind_pics_4]=findpeaks(liste8);
        duree_pics=[];
        duree_pics_4=[];
        
        %high frequency
        
        for elem3=1:length(pics)
            compteur_duree=0;
            while liste4( ind_pics(elem3) + compteur_duree ) > 0
                compteur_duree = compteur_duree + 1;
            end
             duree_pics = [duree_pics ; compteur_duree/fs];
            if compteur_duree < dureeminpic_nb
                liste4( ind_pics(elem3) : ind_pics(elem3) + compteur_duree ) = zeros( compteur_duree + 1 , 1 );
                duree_pics(length(duree_pics))=[];    %% PB : ça a pas l'air de supprimer toutes les durées < dureemin    PBPBPBPBPBPBPBPBPPBPBPBPBP
            end    
        end    


        %low frequency
        
        for elem7=1:length(pics_4)
            compteur_duree=0;
            while liste8( ind_pics_4(elem7) + compteur_duree ) > 0
            compteur_duree = compteur_duree + 1;
            end
            duree_pics_4 = [duree_pics_4 ; compteur_duree/fs];
            if compteur_duree < dureeminpic_nb
                liste8( ind_pics_4(elem7) : ind_pics_4(elem7) + compteur_duree ) = zeros( compteur_duree + 1 , 1 );
                duree_pics_4(length(duree_pics_4))=[];    %% PB : ça a pas l'air de supprimer toutes les durées < dureemin    PBPBPBPBPBPBPBPBPPBPBPBPBP
            end    
        end    

        % Suppression of events according to the frequencies (min and max) and the number of spikes of the spike burst chosen by the user
        liste5=liste4;
        liste9=liste8;
        [pics2,ind_pics2]=findpeaks(liste5);
        [pics_5,ind_pics_5]=findpeaks(liste9);
        duree_pics2=[];
        duree_pics_5=[];

        %high frequency
        
        for elem5=1:length(pics2)
            compteur_duree=0;
            while liste5( ind_pics2(elem5) + compteur_duree ) == pics2(elem5)
            compteur_duree = compteur_duree + 1;
            end
            duree_pics2 = [duree_pics2 ; compteur_duree/fs];         %% PB : ça a pas l'air de supprimer toutes les durées < dureemin
        end     
    
        % low frequency
        
        for elem7=1:length(pics_5)
            compteur_duree=0;
            while liste9( ind_pics_5(elem7) + compteur_duree ) == pics_5(elem7)
            compteur_duree = compteur_duree + 1;
            end
            duree_pics_5 = [duree_pics_5 ; compteur_duree/fs];         %% PB : ça a pas l'air de supprimer toutes les durées < dureemin
        end     

        
        % reunion of close HFO events in one event
        
        ind_pics2_new=[];
        diff_indices_pics = diff(ind_pics2);
        compteur_sup = 0;
        for elem8=1:length(diff_indices_pics)
            if diff_indices_pics(elem8) < 80
                compteur_sup = compteur_sup +1;
                if (elem8+compteur_sup)>length(diff_indices_pics)
                break
                end
            else
                ind_pics2_new= [ind_pics2_new ind_pics2(elem8-compteur_sup,:)];
                compteur_sup=0;
            end
        end
        
        ind_pics2_new=ind_pics2_new';
        duree_pics2_new=duree_pics2(find(ind_pics2_new));

        %keep only IID
        
        events=[ind_pics_5 duree_pics_5];
        events(:,1)=events(:,1)+512;
        keep_events = [];
        
        for elem9=1:length(events)-1    
          
            
          signal = (y(events(elem9,1)-events(elem9,2)*512:(events(elem9,1)+2*events(elem9,2)*512))); %change here the selection window for IID if the detection is not good

          if length(signal) > 20 
              first_10 = signal(1:10);
              last_10 = signal(end-10:end);
             
              if median(first_10)>median(signal)-0.03 && median(last_10)>median(signal)-0.03
                  
                  dsignal = diff(signal<median(signal)-0.03);
                  ts = find(dsignal == 1);
                  te = find(dsignal == -1);
                  disp(elem9)

                  if length (ts) > length (te)
                      te=[te; length(signal)];
                  end
                  
                  if length (ts) < length (te)
                     ts=[0; ts]; 
                  end
                  
                  l = te-ts;
                  disp(l);
              else
                  l=0;
              end
              
              if (find(l>=5)>=1)
                  keep_events=[keep_events; events(elem9,:)];
              end
              
          end
         
        end
        
        % HFO and IID burst
        
        
        % high frequency 
        
        infos_bouffees=[];
        duree_pics_nb = duree_pics2_new*fs;
        compteur_pics = 1;
        for elem4=1:length(ind_pics2_new)-1 
            if (ind_pics2_new(elem4+1) - ind_pics2_new(elem4) - duree_pics_nb(elem4))/fs < Tmax && (ind_pics2_new(elem4+1) - ind_pics2_new(elem4) - duree_pics_nb(elem4))/fs > Tmin 
            compteur_pics = compteur_pics + 1;
                if compteur_pics == 2
                start_bouffee = ind_pics2_new(elem4);
                end
            else
                if compteur_pics > nb_min_pics
                    fin_bouffee = ind_pics2_new(elem4) + duree_pics_nb(elem4)/fs;
                    infos_bouffees = [infos_bouffees ;start_bouffee fin_bouffee compteur_pics];
                    compteur_pics = 1;
                else 
                    compteur_pics = 1;
                end    
            end    
        end
        if compteur_pics > nb_min_pics
            fin_bouffee = ind_pics2_new(elem4+1) + duree_pics_nb(elem4+1)/fs;
            infos_bouffees = [infos_bouffees ;start_bouffee fin_bouffee compteur_pics];
            compteur_pics = 1;
        end
        
        
        if length(infos_bouffees)==0
            nb_bouffees=0;
        else  
            nb_bouffees=length(infos_bouffees(:,1));
        end
      
        %lowfrequency
        
        if isempty(keep_events)==0;
            infos_bouffees_2=[];
            duree_pics_nb_2 = keep_events(:,2)*fs;
            compteur_pics = 1;
            ind_pics_nb_2 = keep_events(:,1);
            for elem6=1:length(ind_pics_nb_2)-1 
                if (ind_pics_nb_2(elem6+1) - ind_pics_nb_2(elem6) - duree_pics_nb_2(elem6))/fs < Tmax && (ind_pics_nb_2(elem6+1) - ind_pics_nb_2(elem6) - duree_pics_nb_2(elem6))/fs > Tmin 
                compteur_pics = compteur_pics + 1;
                    if compteur_pics == 2
                    start_bouffee_2 = ind_pics_nb_2(elem6);
                    end
                else
                    if compteur_pics > nb_min_pics
                        fin_bouffee_2 = ind_pics_nb_2(elem6) + duree_pics_nb_2(elem6)/fs;
                        infos_bouffees_2 = [infos_bouffees_2 ;start_bouffee_2 fin_bouffee_2 compteur_pics];
                        compteur_pics = 1;
                    else 
                        compteur_pics = 1;
                    end    
                end    
            end
            if compteur_pics > nb_min_pics
                fin_bouffee_2 = ind_pics_nb_2(elem6+1) + duree_pics_nb_2(elem6+1)/fs;
                infos_bouffees_2 = [infos_bouffees_2 ;start_bouffee_2 fin_bouffee_2 compteur_pics];
                compteur_pics = 1;
            end
        
        
            if length(infos_bouffees_2)==0
                nb_bouffees_2=0;
            else  
                nb_bouffees_2=length(infos_bouffees_2(:,1));
            end
        else
            infos_bouffees_2=[];
            nb_bouffees_2=0;   
        end
        
        %% Results
        fprintf('According to you, in this recording, there are : \n - %d outliers \n', nb_abe );      
        fprintf('- %d disconnections \n ', nb_pts_en_plus );
        fprintf('- %d HFO burst(s) \n ', nb_bouffees);
        fprintf('- %d IID burst(s) \n ', nb_bouffees_2);
        if nb_bouffees > 0
        Temps1=[];    
        Temps2=[]; 
        
        for l=1:nb_bouffees
        temps1=time_format_(round(infos_bouffees(l,1)));   
        Temps1=[Temps1 ; temps1];
        temps2=time_format_(round(infos_bouffees(l,2))); 
        Temps2=[Temps2 ; temps2];
     
       
        formatSpec = 'The HFO bursting number %d begins at %s and finishes at %s  which corresponds to a number of %d spikes.\n';
        fprintf(formatSpec, l,temps1,temps2,infos_bouffees(l,3) );
        end 
        end
        fprintf('We specify that all positions in time are calculated to the nearest 0.1s, which can lead to errors.\n');
        
        
        if nb_bouffees_2 > 0
        Temps3=[];    
        Temps4=[]; 
        for l=1:nb_bouffees_2
        temps3=time_format_(round(infos_bouffees_2(l,1)));   
        Temps3=[Temps3 ; temps3];
        temps4=time_format_(round(infos_bouffees_2(l,2))); 
        Temps4=[Temps4 ; temps4]; 


        formatSpec = 'The IID bursting number %d begins at %s and finishes at %s  which corresponds to a number of %d spikes.\n';
        fprintf(formatSpec, l,temps3,temps4,infos_bouffees_2(l,3) );
        end 
        end
        fprintf('We specify that all positions in time are calculated to the nearest 0.1s, which can lead to errors.\n');


        % Save new data
        save( sprintf( '%s.mat', file_affichage(1:length(file_affichage)-4)) , 'infos_bouffees' , 'infos_bouffees_2', 'nb_bouffees', 'nb_bouffees_2' , '-append' )
        
        % Plot
        
        Time_HFO = time_format_(ind_pics2_new);
        Id_HFO = 1:length(Time_HFO);
        Id_HFO = Id_HFO';
        
        y1=y(512:length(y)-512);
        %liste5=liste5(512:length(liste5)-512);
        %sommeW1=sommeW1(512:length(sommeW1)-512);
        
        fig=uifigure('Name','Graphics Display');
        fig.AutoResizeChildren = 'off';
        datacursormode(fig)
        ax1 = subplot(3,1,1,'Parent',fig);
        plot(ax1,time_format_ , y1 )
        hold(ax1, 'on');
        plot(ax1,Time_HFO,y(Id_HFO),'*r')
        text(ax1,0.01,0.01, date_, ...
             'Units', 'normalized', ...   
             'HorizontalAlignment', 'left', ...
             'VerticalAlignment', 'bottom');
        title(ax1 , 'Signal without outliers' )
        xlabel(ax1,'Time (in hours:minutes:seconds)')
        ylabel(ax1,'Voltage (in millivolts)')        
        ax2 = subplot(3,1,2,'Parent',fig);
        plot(ax2, time_format_ , sommeW1 )
        title(ax2 , 'Walsh metric HFO' )
        xlabel(ax2,'Time (in hours:minutes:seconds)')
        ylabel(ax2,'Walsh metric')   
        ax3 = subplot(3,1,3,'Parent',fig);
        plot(ax3, time_format_ , liste5)
        title(ax3 , 'Modified Walsh metric HFO' )
        xlabel(ax3,'Time (in hours:minutes:seconds)')
        ylabel(ax3,'Walsh metric')        
        linkaxes([ax1 ax2 ax3],'x')     
        
        %lowfrequency


        y2=y(512:length(y)-512);
        %liste5=liste5(512:length(liste5)-512);
        %sommeW1=sommeW1(512:length(sommeW1)-512);
        
        if isempty(keep_events)==0
            events_new=keep_events(:,1)-512;
            Time_events = time_format_(events_new);
        
            fig=uifigure('Name','Graphics Display');
            fig.AutoResizeChildren = 'off';
            datacursormode(fig)
            ax1 = subplot(3,1,1,'Parent',fig);
            plot(ax1, time_format_ , y2 )
            hold(ax1, 'on');
            plot(ax1,Time_events,y(events_new),'*r')
            text(ax1,0.01,0.01, date_, ...
             'Units', 'normalized', ...   
             'HorizontalAlignment', 'left', ...
             'VerticalAlignment', 'bottom');
            title(ax1 , 'Signal without outliers' )
            xlabel(ax1,'Time (in hours:minutes:seconds)')
            ylabel(ax1,'Voltage (in millivolts)')        
            ax2 = subplot(3,1,2,'Parent',fig);
            plot(ax2, time_format_ , sommeW2 )
            title(ax2 , 'Walsh metric IID' )
            xlabel(ax2,'Time (in hours:minutes:seconds)')
            ylabel(ax2,'Walsh metric')   
            ax3 = subplot(3,1,3,'Parent',fig);
            plot(ax3, time_format_ , liste9 )
            title(ax3 , 'Modified Walsh metric IID' )
            xlabel(ax3,'Time (in hours:minutes:seconds)')
            ylabel(ax3,'Walsh metric')        
            linkaxes([ax1 ax2 ax3],'x') 
           
        toc
        else
        end
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%% Save in the excel file %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        % Spikes 
        
        % HFOs
        Time_HFO = time_format_(ind_pics2_new);
        Id_HFO = 1:length(Time_HFO);
        Id_HFO = Id_HFO'
        if length(Time_HFO) >= 1
        Tab2=table(Id_HFO,Time_HFO);
        writetable( Tab2, sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)),'Sheet','Feuil2','Range','A1');
        end
       
        if nb_bouffees >= 1
        Id=( 1:nb_bouffees )';
        Start = Temps1;
        End= Temps2;
        HFO_Number = infos_bouffees(:,3);
        nb_heure=datestr( time_format_( length(time_format_) ), 13 );
 
        Tab=table(Id,Start,End,HFO_Number);
        
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{'SEIZURES'},'Feuil1','A1');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{'hours:minutes:seconds'},'Feuil1','G2');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('%d Outliers', nb_abe ) },'Feuil1','K3');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('%d Disconnections', nb_pts_en_plus ) },'Feuil1','K4');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('%d HFO Bursts', nb_bouffees ) },'Feuil1','L5');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('%s', date_ ) },'Feuil1','K6');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('%s hours of recording', nb_heure ) },'Feuil1','K7');      
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{'HFO BURSTS'},'Feuil1','F1');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ 'User key factors' },'Feuil1','M3');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('Threshold HFO : %.1f', seuil_pointes ) },'Feuil1','M6');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('Min HFO duration : %.3f', dureeminpic ) },'Feuil1','M7');        
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('Tmin and Tmax : %.1f and %.1f', Tmin, Tmax ) },'Feuil1','M8');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('Min successive HFO : %d', nb_min_pics ) },'Feuil1','M9');
        writetable( Tab, sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)),'Sheet','Feuil1','Range','F3');
        
        
        end
        
        % IID
        
        if isempty(keep_events)==0
        Time_IID = time_format_(keep_events(:,1));
        Id_IID = 1:length(Time_IID);
        Id_IID = Id_IID'
        if length(Time_IID) >= 1
        Tab3=table(Id_IID,Time_IID);
        writetable( Tab3, sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)),'Sheet','Feuil2','Range','D1');
        end
       
        if nb_bouffees_2 >= 1
        Id_2=( 1:nb_bouffees_2 )';
        Start_2 = Temps3;
        End_2= Temps4;
        IID_Number = infos_bouffees_2(:,3);
        nb_heure=datestr( time_format_( length(time_format_) ), 13 );
 
        Tab_2=table(Id_2,Start_2,End_2,IID_Number);
        
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{'SEIZURES'},'Feuil1','A1');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{'hours:minutes:seconds'},'Feuil1','R2');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('%d Outliers', nb_abe ) },'Feuil1','V3');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('%d Disconnections', nb_pts_en_plus ) },'Feuil1','V4');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('%d IID Bursts', nb_bouffees_2 ) },'Feuil1','W5');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('%s', date_ ) },'Feuil1','V6');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('%s hours of recording', nb_heure ) },'Feuil1','V7');      
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{'IID BURSTS'},'Feuil1','Q1');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ 'User key factors' },'Feuil1','X3');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('Threshold IID : %.1f', seuil_pointes_2 ) },'Feuil1','X6');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('Min spike duration : %.3f', dureeminpic ) },'Feuil1','X7');        
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('Tmin and Tmax : %.1f and %.1f', Tmin, Tmax ) },'Feuil1','X8');
        xlswrite( sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)) ,{ sprintf('Min succesive IID : %d', nb_min_pics ) },'Feuil1','X9');
        writetable( Tab_2, sprintf( 'Seizures_%s.xlsx', file_affichage(9:length(file_affichage)-4)),'Sheet','Feuil1','Range','Q3');
        
        
        end
        
        dossier=path_affichage(1:length(path_affichage)-8);
        cd(dossier)        
        
        else
        end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Step1Menu = uimenu(app.UIFigure);
            app.Step1Menu.Text = 'Step 1';
            app.FileUploadandPreProcessingMenu = uimenu(app.Step1Menu);
            app.FileUploadandPreProcessingMenu.MenuSelectedFcn = createCallbackFcn(app, @FileUploadandPreProcessingMenuSelected, true);
            app.FileUploadandPreProcessingMenu.Text = 'File Upload and Pre-Processing ';
            app.OutliersMenu = uimenu(app.Step1Menu);
            app.OutliersMenu.MenuSelectedFcn = createCallbackFcn(app, @OutliersMenuSelected, true);
            app.OutliersMenu.Text = 'Outliers';
            app.ComputingMenu = uimenu(app.Step1Menu);
            app.ComputingMenu.MenuSelectedFcn = createCallbackFcn(app, @ComputingMenuSelected, true);
            app.ComputingMenu.Text = 'Computing';
            app.Step2SeizuresMenu = uimenu(app.UIFigure);
            app.Step2SeizuresMenu.Text = 'Step 2 : Seizures';
            app.GraphicsdisplayMenu = uimenu(app.Step2SeizuresMenu);
            app.GraphicsdisplayMenu.MenuSelectedFcn = createCallbackFcn(app, @GraphicsdisplayMenuSelected2, true);
            app.GraphicsdisplayMenu.Text = 'Graphics display';
            app.ResultsMenu = uimenu(app.Step2SeizuresMenu);
            app.ResultsMenu.MenuSelectedFcn = createCallbackFcn(app, @ResultsMenuSelected, true);
            app.ResultsMenu.Text = 'Results';
            app.Step3InterictalspikesMenu = uimenu(app.UIFigure);
            app.Step3InterictalspikesMenu.Text = 'Step 3 : Interictal spikes';
            app.GraphicsDisplayMenu = uimenu(app.Step3InterictalspikesMenu);
            app.GraphicsDisplayMenu.MenuSelectedFcn = createCallbackFcn(app, @GraphicsDisplayMenuSelected, true);
            app.GraphicsDisplayMenu.Text = 'Graphics Display';
            app.ResultsMenu_2 = uimenu(app.Step3InterictalspikesMenu);
            app.ResultsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ResultsMenu_2Selected, true);
            app.ResultsMenu_2.Text = 'Results';
            app.EpilepsyDetectionAlgorithmLabel = uilabel(app.UIFigure);
            app.EpilepsyDetectionAlgorithmLabel.Text = 'Epilepsy Detection Algorithm';
            app.WhatcanthisalgorithmdoLabel = uilabel(app.UIFigure);
            app.WhatcanthisalgorithmdoLabel.Text = 'What can this algorithm do ?';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {'- Loading a text file from the LWDAQ software (refer to the .txt template file)'; '- Remove outliers with the help of the user'; '- Detection of seizures thanks to an energy/duration metric  and parameters chosen by the user'; '- Detection of spike bursts thanks to an Walsh metric and parameters chosen by the user'; '- Information stored in a Seizures_Name files (.xlsx and .mat)'; ''};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'IFM.jpg';
            app.ThemetricLabel = uilabel(app.UIFigure);
            app.ThemetricLabel.Text = 'The metric';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {'Uses an energetic approach coupled with a temporal approach.'; 'These two metrics are combined in a Global Parameter, the Combined Feature (CF).'};
            app.KeyfactorsLabel = uilabel(app.UIFigure);
            app.KeyfactorsLabel.Text = 'Key factors';
            app.MinimunseizuredurationLabel = uilabel(app.UIFigure);
            app.MinimunseizuredurationLabel.Text = 'Minimun seizure duration';
            app.dureemin = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdLabel = uilabel(app.UIFigure);
            app.ThresholdLabel.Text = 'Threshold';
            app.seuil = uieditfield(app.UIFigure, 'numeric');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = 'To be filled in before clicking on Step 2 / Results';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = 'To be filled in before clicking on Step 3 / Results';
            app.MinimuninterrictalspikedurationLabel = uilabel(app.UIFigure);
            app.MinimuninterrictalspikedurationLabel.Text = {'Minimun interrictal '; 'spike duration'};
            app.dureeminpic_ = uieditfield(app.UIFigure, 'numeric');
            app.MinimumnumberofsuccesivespikesLabel = uilabel(app.UIFigure);
            app.MinimumnumberofsuccesivespikesLabel.Text = {'Minimum number '; 'of succesive spikes'};
            app.nb_min_pics_ = uieditfield(app.UIFigure, 'numeric');
            app.MinimumdurationbetweentwospikesLabel = uilabel(app.UIFigure);
            app.MinimumdurationbetweentwospikesLabel.Text = {'Minimum duration '; 'between two spikes'};
            app.Tmin_ = uieditfield(app.UIFigure, 'numeric');
            app.MaximumdurationbetweentwospikesLabel = uilabel(app.UIFigure);
            app.MaximumdurationbetweentwospikesLabel.Text = {'Maximum duration '; 'between two spikes'};
            app.Tmax_ = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdhighfrequencyLabel = uilabel(app.UIFigure);
            app.ThresholdhighfrequencyLabel.Text = {'Threshold'; 'highfrequency'};
            app.seuil_pic = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdlowfrequencyLabel = uilabel(app.UIFigure);
            app.ThresholdlowfrequencyLabel.Text = {'Threshold'; 'lowfrequency'};
            app.seuil_pic_2 = uieditfield(app.UIFigure, 'numeric');
            app.EnergytresholdLabel = uilabel(app.UIFigure);
            app.EnergytresholdLabel.Text = 'Energy treshold';
            app.EnergytresholdLabel_2 = uilabel(app.UIFigure);
            app.EnergytresholdLabel_2.Text = 'Energy treshold';
            app.Energytreshold1 = uieditfield(app.UIFigure, 'numeric');
            app.Energytreshold1.ValueDisplayFormat = '%e';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Epilepsy_Detection_old_FD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end